classdef WP2app_Excel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PitchAngleEditField        matlab.ui.control.NumericEditField
        PitchAngleEditFieldLabel   matlab.ui.control.Label
        RollAngleEditField         matlab.ui.control.NumericEditField
        RollAngleEditFieldLabel    matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        RightSignalEditField       matlab.ui.control.NumericEditField
        RightSignalEditFieldLabel  matlab.ui.control.Label
        LeftSignalEditField        matlab.ui.control.NumericEditField
        LeftSignalEditFieldLabel   matlab.ui.control.Label
        totalangle                 matlab.ui.control.UIAxes
        potentialvalue             matlab.ui.control.UIAxes
        simulation                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Start the serial connection
            s = serialport('COM8', 9600);
            configureTerminator(s, "CR/LF");

            O  = [0,0,0]';
            e1 = [0,0,1]';
            e2 = [1,0,0]';
            e3 = [0,1,0]';

            p1 = plot3(app.simulation, [O(1), e1(1)], ...
                [O(2), e1(2)], ...
                [O(3), e1(3)], ...
                'LineWidth',2,'Color','r');
            hold(app.simulation, 'on');
            p2 = plot3(app.simulation, [O(1), e2(1)], ...
                [O(2), e2(2)], ...
                [O(3), e2(3)], ...
                'LineWidth',2,'Color','g');
            p3 = plot3(app.simulation, [O(1), e3(1)], ...
                [O(2), e3(2)], ...
                [O(3), e3(3)], ...
                'LineWidth',2,'Color','b');
            %hold(app.simulation, 'off');
            
            % Create animatedline for left
            h = animatedline(app.potentialvalue, 'Color', 'b', 'LineWidth',2, 'MaximumNumPoints', 1500);
            % Create animatedline for Right
            h1 = animatedline(app.potentialvalue, 'Color', 'r', 'LineWidth',2, 'MaximumNumPoints', 1500);

            hold(app.potentialvalue, 'on');

            legend(app.potentialvalue, "Left", "Right");
            % Create animatedline for Total Angle
            % hold(app.potentialvalue, 'off');
            h2 = animatedline(app.totalangle, 'Color', 'r', 'LineWidth',2, 'MaximumNumPoints',1500);

            % Initialization
            data  = str2double(regexp(readline(s), '[-+]?\d*\.?\d+', 'match'));
            time_start = data(1);
            while 1
                data = str2double(regexp(readline(s), '[-+]?\d*\.?\d+', 'match'));

                % Create variables
                time = data(1);
                left = data(2);
                right = data(3);
                pitch = data(4);
                roll = data(5);

                % Add data to table
                datatable = table(time, left, right, roll);

                % Write table to Excel
                writetable(datatable, "WP2data.xlsx", "WriteMode", "append");
            
                time = data(1);
                if (time-time_start) >= 20
                    time_start = time;
                    
                    th1 = 0;
                    th2 = data(4);
                    th3 = data(5);
                    
                    R1 = [cosd(th1), -sind(th1), 0;
                          sind(th1),  cosd(th1), 0;
                                  0,          0, 1];
                    R2 = [1,         0,          0;
                          0, cosd(th2), -sind(th2);
                          0, sind(th2),  cosd(th2)];
                    R3 = [ cosd(th3), 0, sind(th3);
                                   0, 1,         0;
                          -sind(th3), 0, cosd(th3)];

                    e1_pr = R3*R2*R1*e1;
                    e2_pr = R3*R2*R1*e2;
                    e3_pr = R3*R2*R1*e3;
                    
                    % Plot 3D data
                    set(p1, 'XData', [O(1), e1_pr(1)], ...
                            'YData', [O(2), e1_pr(2)], ...
                            'ZData', [O(3), e1_pr(3)])
                    set(p2, 'XData', [O(1), e2_pr(1)], ...
                            'YData', [O(2), e2_pr(2)], ...
                            'ZData', [O(3), e2_pr(3)])
                    set(p3, 'XData', [O(1), e3_pr(1)], ...
                            'YData', [O(2), e3_pr(2)], ...
                            'ZData', [O(3), e3_pr(3)])
                    drawnow;
                    
                    % Plot 2D data
                    addpoints(h, data(1)/1000, left);
                    addpoints(h1,data(1)/1000, right);
                    addpoints(h2,data(1)/1000, roll);
                    drawnow;

                    % Update value of data to display
                    app.LeftSignalEditField.Value = left;
                    app.RightSignalEditField.Value = right;
                    app.PitchAngleEditField.Value = pitch;
                    app.RollAngleEditField.Value = roll;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.simulation = uiaxes(app.UIFigure);
            title(app.simulation, 'IMU')
            xlabel(app.simulation, 'x')
            ylabel(app.simulation, 'y')
            zlabel(app.simulation, 'z')
            app.simulation.XLim = [-1 1];
            app.simulation.YLim = [-1 1];
            app.simulation.ZLim = [-1 1];
            app.potentialvalue = uiaxes(app.UIFigure);
            title(app.potentialvalue, 'Left/Right Control Signal vs Time')
            xlabel(app.potentialvalue, 'Time (ms)')
            ylabel(app.potentialvalue, 'Control Signal')
            zlabel(app.potentialvalue, 'Z')
            app.potentialvalue.YLim = [0 2000];
            app.totalangle = uiaxes(app.UIFigure);
            title(app.totalangle, 'Roll vs Time')
            xlabel(app.totalangle, 'Time (ms)')
            ylabel(app.totalangle, 'Roll Angle (degrees)')
            zlabel(app.totalangle, 'Z')
            app.totalangle.YLim = [-90 90];
            app.LeftSignalEditFieldLabel = uilabel(app.UIFigure);
            app.LeftSignalEditFieldLabel.Text = 'Left Signal';
            app.LeftSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftSignalEditField.Editable = 'off';
            app.RightSignalEditFieldLabel = uilabel(app.UIFigure);
            app.RightSignalEditFieldLabel.Text = 'Right Signal';
            app.RightSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightSignalEditField.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RollAngleEditFieldLabel = uilabel(app.UIFigure);
            app.RollAngleEditFieldLabel.Text = 'Roll Angle';
            app.RollAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchAngleEditFieldLabel = uilabel(app.UIFigure);
            app.PitchAngleEditFieldLabel.Text = 'Pitch Angle';
            app.PitchAngleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WP2app_Excel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end