classdef WP2app_ExcelOnly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PitchAngleEditField        matlab.ui.control.NumericEditField
        PitchAngleEditFieldLabel   matlab.ui.control.Label
        RollAngleEditField         matlab.ui.control.NumericEditField
        RollAngleEditFieldLabel    matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        RightSignalEditField       matlab.ui.control.NumericEditField
        RightSignalEditFieldLabel  matlab.ui.control.Label
        LeftSignalEditField        matlab.ui.control.NumericEditField
        LeftSignalEditFieldLabel   matlab.ui.control.Label
        totalangle                 matlab.ui.control.UIAxes
        potentialvalue             matlab.ui.control.UIAxes
        simulation                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Start the serial connection
            s = serialport('COM8', 9600);
            configureTerminator(s, "CR/LF");

            while 1
                data = str2double(regexp(readline(s), '[-+]?\d*\.?\d+', 'match'));

                % Create variables
                time = data(1);
                left = data(2);
                right = data(3);
                roll = data(5);

                % Add data to table
                datatable = table(time, left, right, roll);

                % Write table to Excel
                writetable(datatable, "WP2data.xlsx", "WriteMode", "append");
            
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.simulation = uiaxes(app.UIFigure);
            title(app.simulation, 'IMU')
            xlabel(app.simulation, 'x')
            ylabel(app.simulation, 'y')
            zlabel(app.simulation, 'z')
            app.simulation.XLim = [-1 1];
            app.simulation.YLim = [-1 1];
            app.simulation.ZLim = [-1 1];
            app.potentialvalue = uiaxes(app.UIFigure);
            title(app.potentialvalue, 'Left/Right Control Signal vs Time')
            xlabel(app.potentialvalue, 'Time (ms)')
            ylabel(app.potentialvalue, 'Control Signal')
            zlabel(app.potentialvalue, 'Z')
            app.potentialvalue.YLim = [0 2000];
            app.totalangle = uiaxes(app.UIFigure);
            title(app.totalangle, 'Roll vs Time')
            xlabel(app.totalangle, 'Time (ms)')
            ylabel(app.totalangle, 'Roll Angle (degrees)')
            zlabel(app.totalangle, 'Z')
            app.totalangle.YLim = [-90 90];
            app.LeftSignalEditFieldLabel = uilabel(app.UIFigure);
            app.LeftSignalEditFieldLabel.Text = 'Left Signal';
            app.LeftSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftSignalEditField.Editable = 'off';
            app.RightSignalEditFieldLabel = uilabel(app.UIFigure);
            app.RightSignalEditFieldLabel.Text = 'Right Signal';
            app.RightSignalEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightSignalEditField.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RollAngleEditFieldLabel = uilabel(app.UIFigure);
            app.RollAngleEditFieldLabel.Text = 'Roll Angle';
            app.RollAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchAngleEditFieldLabel = uilabel(app.UIFigure);
            app.PitchAngleEditFieldLabel.Text = 'Pitch Angle';
            app.PitchAngleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WP2app_ExcelOnly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end